classdef Stim_compute < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TremormuscleButtonGroup        matlab.ui.container.ButtonGroup
        Muscle2Button                  matlab.ui.control.RadioButton
        Muscle1Button                  matlab.ui.control.RadioButton
        SettingsPanel                  matlab.ui.container.Panel
        PhaseButtonGroup               matlab.ui.container.ButtonGroup
        OutphaseButton                 matlab.ui.control.RadioButton
        InphaseButton                  matlab.ui.control.RadioButton
        TremorthresholdEditField       matlab.ui.control.NumericEditField
        TremorthresholdEditFieldLabel  matlab.ui.control.Label
        ComputeButton                  matlab.ui.control.Button
        EMGDropDown                    matlab.ui.control.DropDown
        EMGDropDownLabel               matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        ImportButton                   matlab.ui.control.Button
        VisualizationPanel             matlab.ui.container.Panel
        LeftButton                     matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        ZoominButton                   matlab.ui.control.Button
        ZoomoutButton                  matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        EMG_type=1; % Description
        fsamp;
        emg;
        tremor_thresh;
        time;
        filename;
        pathname;
        graphstart;
        graphend;
        muscle = 1;
        phase = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            if app.EMG_type == 2
                [app.filename,app.pathname] = uigetfile('*.otb*',  'OTB Files (*.otb*)');
                app.EditField.Value = app.filename;
                signal = openOTBplus(app.pathname, app.filename, 0);
                app.fsamp = 2048;
                for i = 1:signal.ngrid
                    [signal.coordinates{i}, signal.IED(i), signal.EMGmask{i}, signal.emgtype(i)] = formatsignalHDEMG(signal.data((i-1)*64+1:i*64,:), signal.gridname{i}, signal.fsamp, 1);
                    app.emg{i} = signal.data((i-1)*64+1:i*64,:) - signal.data((i-1)*64+1:i*64,:).*signal.EMGmask{i};
                end
                app.time = linspace(0,size(app.emg{1}(1,:),2)/signal.fsamp,size(app.emg{1}(1,:),2));
                plot(app.UIAxes, app.time, app.emg{1}(46,:), 'LineWidth', 2, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxes_2, app.time, app.emg{2}(46,:), 'LineWidth', 2, 'Color', [0.9412 0.9412 0.9412]);
            elseif app.EMG_type == 1
                [app.filename,app.pathname] = uigetfile('*.mat*',  'MAT Files (*.mat*)');
                app.EditField.Value = app.filename;
                signal = load([app.pathname app.filename]);
                app.emg{1} = signal.EMG_raw(1,:);
                app.emg{2} = signal.EMG_raw(2,:);
                app.fsamp = 2000;
                app.time = linspace(0,size(app.emg{1,1}(:)',2)/app.fsamp,size(app.emg{1,1}(:)',2));
                plot(app.UIAxes, app.time, app.emg{1,1}(:)', 'LineWidth', 2, 'Color', [0.9412 0.9412 0.9412]);
                plot(app.UIAxes_2, app.time, app.emg{1,2}(:)', 'LineWidth', 2, 'Color', [0.9412 0.9412 0.9412]);
            end
            app.graphstart = app.time(1);
            app.graphend = app.time(end);
            
        end

        % Value changed function: EMGDropDown
        function EMGDropDownValueChanged(app, event)
            value = app.EMGDropDown.Value;
            if value == "BiEMG"
                app.EMG_type = 1;
            elseif value == "HdEMG"
                app.EMG_type = 2;
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            plot(app.UIAxes,0,0);
            plot(app.UIAxes_2,0,0);
            if app.EMG_type == 2
                for i = 1:2
                    signal_processed(i,:) = mean([app.emg{1,i}(20,:);app.emg{1,i}(31,:);app.emg{1,i}(46,:);app.emg{1,i}(19,:);app.emg{1,i}(32,:);app.emg{1,i}(45,:)],1) - mean([app.emg{1,i}(11,:);app.emg{1,i}(14,:);app.emg{1,i}(37,:);app.emg{1,i}(12,:);app.emg{1,i}(13,:);app.emg{1,i}(38,:)],1);
                end
            elseif app.EMG_type == 1
                signal_processed = [app.emg{1,1}(:)';app.emg{1,2}(:)'];
            end
            [signal_env, signal_bursts] = BiEMG_tremor_detection(signal_processed, app.fsamp, app.tremor_thresh, app.muscle);
            plot(app.UIAxes,app.time,signal_processed(1,:), 'linewidth', 2, 'Color', [0.9412 0.9412 0.9412])
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.time,signal_env(1,:),'linewidth',1.2,'Color',[0, 0.75, 0.75, 0.5])
            if app.muscle == 1 && app.phase == 1
                plot(app.UIAxes,app.time,signal_bursts(app.muscle,:),'linewidth',1.5,'Color',[0.8500, 0.3250, 0.0980, 0.5])
            elseif app.muscle == 2 && app.phase == 2
                plot(app.UIAxes,app.time,signal_bursts(app.muscle,:),'linewidth',1.5,'Color',[0.8500, 0.3250, 0.0980, 0.5])
            end
            hold(app.UIAxes,'off');
            app.UIAxes_2.XLim = [app.graphstart app.graphend];

            plot(app.UIAxes_2,app.time,signal_processed(2,:), 'linewidth', 2, 'Color', [0.9412 0.9412 0.9412])
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2,app.time,signal_env(2,:),'linewidth',1.2,'Color',[0, 0.75, 0.75, 0.5])
            if app.muscle == 2 && app.phase == 1
                plot(app.UIAxes_2,app.time,signal_bursts(app.muscle,:),'linewidth',1.5,'Color',[0.8500, 0.3250, 0.0980, 0.5])
            elseif app.muscle == 1 && app.phase == 2
                plot(app.UIAxes_2,app.time,signal_bursts(app.muscle,:),'linewidth',1.5,'Color',[0.8500, 0.3250, 0.0980, 0.5])
            end
            hold(app.UIAxes_2,'off');
            app.UIAxes_2.XLim = [app.graphstart app.graphend];
        end

        % Value changed function: TremorthresholdEditField
        function TremorthresholdEditFieldValueChanged(app, event)
            app.tremor_thresh = app.TremorthresholdEditField.Value;
            %app.tremor_thresh = str2num(app.tremor_thresh);
            
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            center = app.graphstart + duration/2;
            duration = duration * 0.8;
            app.graphstart = center - duration/2;
            app.graphend = center + duration/2;
            app.UIAxes.XLim = [app.graphstart app.graphend];
            app.UIAxes_2.XLim = [app.graphstart app.graphend];
        end

        % Button pushed function: ZoomoutButton
        function ZoomoutButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            center = app.graphstart + duration/2;
            duration = duration * 1.5;
            if duration > (app.time(end) - app.time(1))
                app.graphstart = app.time(1);
                app.graphend = app.time(end);
                app.UIAxes.XLim = [app.graphstart app.graphend];
                app.UIAxes_2.XLim = [app.graphstart app.graphend];
            else
                app.graphstart = center - duration/2;
                app.graphend = center + duration/2;
                app.UIAxes.XLim = [app.graphstart app.graphend];
                app.UIAxes_2.XLim = [app.graphstart app.graphend];
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            step = 0.05 * duration;
            if (app.graphstart - step) < 0
                app.graphstart = 0;
                app.graphend = app.graphstart + duration;
                app.UIAxes.XLim = [app.graphstart app.graphend];
                app.UIAxes_2.XLim = [app.graphstart app.graphend];
            else
                app.graphstart = app.graphstart - step;
                app.graphend = app.graphstart + duration;
                app.UIAxes.XLim = [app.graphstart app.graphend];
                app.UIAxes_2.XLim = [app.graphstart app.graphend];
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            duration = app.graphend - app.graphstart;
            step = 0.05 * duration;
            if (app.graphend + step) > app.time(end)
                app.graphend = app.time(end);
                app.graphstart = app.graphend - duration;
                app.UIAxes.XLim = [app.graphstart app.graphend];
                app.UIAxes_2.XLim = [app.graphstart app.graphend];
            else
                app.graphend = app.graphend + step;
                app.graphstart = app.graphend - duration;
                app.UIAxes.XLim = [app.graphstart app.graphend];
                app.UIAxes_2.XLim = [app.graphstart app.graphend];
            end
        end

        % Selection changed function: TremormuscleButtonGroup
        function TremormuscleButtonGroupSelectionChanged(app, event)
            selectedButton = app.TremormuscleButtonGroup.SelectedObject;
            if selectedButton.Text == 'Muscle 1'
                app.muscle =1;
            elseif selectedButton.Text == 'Muscle 2'
                app.muscle=2;
            end
        end

        % Selection changed function: PhaseButtonGroup
        function PhaseButtonGroupSelectionChanged(app, event)
            selectedButton = app.PhaseButtonGroup.SelectedObject;
            if selectedButton.Text == "Inphase"
                app.phase =1;
            elseif selectedButton.Text == "Outphase"
                app.phase=2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.VisualizationPanel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.ZoomoutButton = uibutton(app.VisualizationPanel, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutButtonPushed, true);
            app.ZoomoutButton.Text = 'Zoom out';
            app.ZoominButton = uibutton(app.VisualizationPanel, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.RightButton = uibutton(app.VisualizationPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.VisualizationPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.ImportButton = uibutton(app.SettingsPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.EditField = uieditfield(app.SettingsPanel, 'text');
            app.EditField.Value = 'NaN';
            app.EMGDropDownLabel = uilabel(app.SettingsPanel);
            app.EMGDropDownLabel.Text = 'EMG';
            app.EMGDropDown = uidropdown(app.SettingsPanel);
            app.EMGDropDown.Items = {'BiEMG', 'HdEMG'};
            app.EMGDropDown.ValueChangedFcn = createCallbackFcn(app, @EMGDropDownValueChanged, true);
            app.EMGDropDown.Value = 'BiEMG';
            app.ComputeButton = uibutton(app.SettingsPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.TremorthresholdEditFieldLabel = uilabel(app.SettingsPanel);
            app.TremorthresholdEditFieldLabel.Text = 'Tremor threshold';
            app.TremorthresholdEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.TremorthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @TremorthresholdEditFieldValueChanged, true);
            app.TremormuscleButtonGroup = uibuttongroup(app.SettingsPanel);
            app.TremormuscleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TremormuscleButtonGroupSelectionChanged, true);
            app.TremormuscleButtonGroup.Title = 'Tremor muscle';
            app.Muscle1Button = uiradiobutton(app.TremormuscleButtonGroup);
            app.Muscle1Button.Text = 'Muscle 1';
            app.Muscle1Button.Value = true;
            app.Muscle2Button = uiradiobutton(app.TremormuscleButtonGroup);
            app.Muscle2Button.Text = 'Muscle 2';
            app.PhaseButtonGroup = uibuttongroup(app.SettingsPanel);
            app.PhaseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhaseButtonGroupSelectionChanged, true);
            app.PhaseButtonGroup.Title = 'Phase';
            app.InphaseButton = uiradiobutton(app.PhaseButtonGroup);
            app.InphaseButton.Text = 'Inphase';
            app.InphaseButton.Value = true;
            app.OutphaseButton = uiradiobutton(app.PhaseButtonGroup);
            app.OutphaseButton.Text = 'Outphase';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Stim_compute

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end